classdef PtSelector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Button_2  matlab.ui.control.Button
        Button    matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

   %==========================================================================
%                           CLASSDEF PtSelector
%==========================================================================
%
% PURPOSE:
%   Defines a MATLAB App for interactively selecting a key anatomical
%   landmark on a 2D lateral view of a skull. The user selects the deepest
%   point of the concavity above the supraorbital ridge. The app then
%   automates the identification of a corresponding posterior point,
%   calculates several preliminary geometric variables, and performs two
%   types of scaling before returning the data to the calling script.
%
% Author: Gadi Herzlinger and Uzy Smilansky (2023). 
%
% Associated with: 
% Mishol N., Herzlinger G., Rak Y., Smilansky U., Carmel L., Gokhman D. 
% (2025). Candidate Denisovan fossils identified through gene regulatory phenotyping.
%==========================================================================

    properties (Access = public)
        Clsts = [] %The position of the mouse pointer in the axes
        PtsInd = [] % The indices of the selected curvature peak points
        PeaksAll = [] %The point index, arc length and curvature value of peak points
        PeaksCrds = [] %The coordinates of curvature Negative peaks
        Bstar = [] %Pointer proximity highlighted peak graphic object
        Bsel = [] %Selceted peaks highlight graphic object
        PPts = [] %Positive peaks on top of skull graphic object
        StLineGbP = [] % (Gb - Posterior) Straight line graphic object
        OpiC = [] %The coordinates of the opisthocranion
        OpiT = [] %The location of the opistocranion as curve length
        StLineGbO = [] % (Gb - Opisthocranion) Straight line graphic object
        PeaksP = [] %Positive peaks array for 
        Done = [] %Flag for closing the app from the calling function
        Four = struct(); %Structure for containing all fourier data - Coefficients, Coorduinates, TotLen, Wn
        Cnt = struct(); %Structure for fetching from calling script - contains point and Fourier data (PtsInds, Coefs & TotLen) for skull scaled by Centroid size
        GOL = struct(); %Structure for fetching from calling script - contains point and Fourier data (PtsInds, Coefs & TotLen) for skull scaled by GOL
        FHTrRt = []; %tangens of Forehead angle - result for return.
        FHYRt = []; %ratio between FHYDiff and that of the skull vertex.
        FHYDiff = [] %difference on the Y axis between forehead and Glabella (like) - should be normalized
    end
    
    methods (Access = public)
        
        function CloseWindow(app)
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, X, Y, CoefsX, CoefsY, TotLen, Name)
            %App controls setup
            app.Done = false;
            app.Button.Enable = "off";
            app.Button.Text = "Finish";
            
            %Axis setup
            hold(app.UIAxes,'on');
            axis(app.UIAxes,'padded');
            axis(app.UIAxes, 'equal');
            app.UIAxes.Title.String = Name; 
            
            %create variables for Fourier and store in designated structure
            %note that low smoothing is applied with 80% of the
            %coefficients being effective and a window size of 20%
            CuDist = (0:TotLen/300:TotLen)';
            Wn = 1./(1+exp((1:1:size(CoefsX))-fix(0.8*size(CoefsX,1)))/fix(0.2*size(CoefsX,1)))';
            Xpred = zeros(size(CuDist));
            Ypred = zeros(size(CuDist));
            for i=1:size(CuDist,1)
                Xpred(i) = Sum4Fourier(TotLen,CoefsX,CuDist(i),Wn);
                Ypred(i) = Sum4Fourier(TotLen,CoefsY,CuDist(i),Wn);
            end
            app.Four.CoefsX = CoefsX;
            app.Four.CoefsY = CoefsY;
            app.Four.CuDist = CuDist;
            app.Four.Wn = Wn;
            app.Four.Coords = [Xpred,Ypred];
            
            %Scatter the original XY coords from the image and plot the
            %calculated line of the Fourier with the smoothing above
            SklSc = scatter(app.UIAxes,X,Y,'.k');
            BndLowPl = plot(app.UIAxes,Xpred,Ypred,'r','LineWidth',2,'LineStyle','--');
            
            %calculate curvature and find peaks with two iterative round of
            %different smootihng. First find generally with very strong
            %smoothing (Neff = 15) and then optimize peak location with
            %curvature calculated on weaker smoothing (Neff = 45). The
            %peaks are only of negative curvature.
            
            % WnP = 1./(1+exp((1:1:size(CoefsX))-15)/3)';
            % KP = CurvCalc(TotLen,CuDist,WnP,CoefsX,CoefsY);
            % [~,PeaksN] = FindPeaks(KP,CuDist,true,false,10);
            
            %Try finding local minima with seperation directly on weak
            %smoothing curve
            WnP = 1./(1+exp((1:1:size(CoefsX))-45)/3)';
            K = CurvCalc(TotLen,CuDist,WnP,CoefsX,CoefsY);
            %[~, app.PeaksAll] = PercPeaks2([],PeaksN,CuDist,K,true,false,10);

            LocMin = find(islocalmin(K,'MinSeparation',10));
            app.PeaksAll = [LocMin, CuDist(LocMin), K(LocMin)];
            
            %Store only potential peaks on the positive side of X as
            %carnia are all from right view critical point will always be
            %on that side. Store coordinates.
            app.PeaksAll = app.PeaksAll(Xpred(app.PeaksAll(:,1))>0,:);
            app.PeaksCrds = [Xpred(app.PeaksAll(:,1)),Ypred(app.PeaksAll(:,1))];

            
            %Finds the opistocrnion (the most posterior point on the
            %figure) and stores it in terms of arc length and coordinates.
            %Note that in the new process this procedure is redundant and
            %not being used.
            Xfun = @(t) Sum4Fourier(TotLen,CoefsX,t,Wn);
            app.OpiT = fminbnd(Xfun,0,TotLen);
            app.OpiC = [Sum4Fourier(TotLen,CoefsX,app.OpiT,Wn),Sum4Fourier(TotLen,CoefsY,app.OpiT,Wn)];
            
            %Plot the potential peaks
            %The selected peak and closest peak to pointer are NaNs but the
            %graphic objects are defined on the app level so it would
            %update by the app event functions
            PeaksPlt = scatter(app.UIAxes,app.PeaksCrds(:,1),app.PeaksCrds(:,2),135,'pb','filled');
            app.Bstar = scatter(app.UIAxes,NaN,NaN,185,'pr','filled'); 
            app.Bsel = scatter(app.UIAxes,NaN,NaN,185,'pr','filled');
            %Opi = scatter(app.UIAxes,app.OpiC(1),app.OpiC(2),150,'ob','filled');
            app.StLineGbP = plot(app.UIAxes,NaN,NaN,'r','LineWidth',2);
            %app.StLineGbO = plot(app.UIAxes,NaN,NaN,'b','LineWidth',2);
            app.PPts = scatter(app.UIAxes,NaN,NaN,185,'dc','filled');


        end

        % Window button motion function: UIFigure
        function UIFigureWindowButtonMotion(app, event)
            %This function highlights the nearest peak to the pointer. This
            %will be selected when clicked
            FgCrd = app.UIFigure.CurrentPoint;
            InAxFlg = FgCrd(1) >= app.UIAxes.Position(1) && FgCrd(1) <= sum(app.UIAxes.Position([1,3]))...
                && FgCrd(2) >= app.UIAxes.Position(2) && FgCrd(2) <= sum(app.UIAxes.Position([2,4]));
            if InAxFlg
                MsCrd = app.UIAxes.CurrentPoint(1,1:2);
                Dist = vecnorm(app.PeaksCrds-MsCrd,2,2);
                app.Clsts = find(Dist==min(Dist));
                app.Bstar.XData = app.PeaksCrds(app.Clsts,1);
                app.Bstar.YData = app.PeaksCrds(app.Clsts,2);
            else
                app.Clsts= [];
                app.Bstar.XData = NaN;
                app.Bstar.YData = NaN;
            end
        end

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
            %Mouse click selects critical point peak
            ClstsN = app.Clsts;
            Coords = app.Four.Coords;
            Wn = app.Four.Wn;
            CuDist = app.Four.CuDist;
            CoefsX = app.Four.CoefsX;
            CoefsY = app.Four.CoefsY;
            TotLen = CuDist(end);

            if ~isempty(ClstsN)
%          %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%                 OLD CODE FOR SELECTION OF TWO POINTS ON THE CURVE
%                 if isempty(app.PtsInd)
%                     app.PtsInd = [app.PeaksAll(ClstsN,2),app.PeaksCrds(ClstsN,1:2)];
%                 else
%                     Lwst = find(app.PtsInd(:,1)==min(app.PtsInd(:,1)));
%                     PtsInd1=app.PtsInd;
%                     if app.PeaksAll(ClstsN,2) < min(app.PtsInd(:,1))
%                         app.PtsInd = [[app.PeaksAll(ClstsN,2),app.PeaksCrds(ClstsN,1:2)];...
%                             app.PtsInd(Lwst,:)];
%                     elseif app.PeaksAll(ClstsN,2) > min(app.PtsInd(:,1))
%                         app.PtsInd = [app.PtsInd(Lwst,:);...
%                             [app.PeaksAll(ClstsN,2),app.PeaksCrds(ClstsN,1:2)]];
%                     end
%                 end
%           %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %Finds the arc-length and coordinates of the critical
                %point's reflection on the negative side of X, at the same
                %Y value
                app.PtsInd = [app.PeaksAll(ClstsN,2),app.PeaksCrds(ClstsN,1:2)];
                Dists = find((Coords(1:end-1,2) < app.PtsInd(1,3) & Coords(2:end,2) > app.PtsInd(1,3))...
                    |(Coords(1:end-1,2) > app.PtsInd(1,3) & Coords(2:end,2) < app.PtsInd(1,3)));
                if numel(Dists)>1
                    for i =1:size(Dists,1)
                        XLoc(i,1) = Sum4Fourier(TotLen,CoefsX,CuDist(Dists(i)),Wn);
                    end
                    Dists = Dists(XLoc==min(XLoc));
                end
                Ys = [Coords(Dists,2),Coords(Dists+1,2)];
                Dists = [CuDist(Dists), CuDist(Dists+1)];
                NewMDist = mean(Dists,2);
                NewMY = Sum4Fourier(TotLen,CoefsY,NewMDist,Wn);
                while abs(NewMY-app.PtsInd(1,3)) > 10e-8
                    if (sign(NewMY-app.PtsInd(1,3)) > 0 && Ys(2) < Ys(1)) || ...
                        (sign(NewMY-app.PtsInd(1,3)) < 0 && Ys(2) > Ys(1))
                        Dists = [NewMDist, Dists(2)];
                        NewMDist = mean(Dists,2); 
                        NewMY = Sum4Fourier(TotLen,CoefsY,NewMDist,Wn);
                    else
                        Dists = [Dists(1),NewMDist];
                        NewMDist = mean(Dists,2); 
                        NewMY = Sum4Fourier(TotLen,CoefsY,NewMDist,Wn);
                    end
                end
                % PtsInd has the arc-length and coordinates of the critical
                % point and its reflection
                app.PtsInd = [app.PtsInd; [NewMDist,Sum4Fourier(TotLen,CoefsX,NewMDist,Wn),NewMY]];
                
                %Finds forehead
                %Note that the forehead point and its dervied variables
                %(height, angle) in this procedure are found using the
                %curvature. These values are not used in the current
                %study as the tangent approach (used in the calling script)
                %was eventualy prefered.

                %Calculates curvature with low smoothing (Neff=22, w=3)
                WnP = 1./(1+exp((1:1:size(CoefsX))-22)/3)';
                KP = CurvCalc(TotLen,CuDist,WnP,CoefsX,CoefsY);
                [app.PeaksP,~] = FindPeaks(KP,CuDist,true,false,3);
                %Finds the peak of the cranium (skull vertex). This is the
                %point where the tangent =0. Tangent is calculated with
                %high smoothing (Neff = 8, w=3)
                WnP = 1./(1+exp((1:1:size(CoefsX))-8)/3)';
                TanFun = @(t) abs(TanCalc(TotLen,t,WnP,CoefsX,CoefsY));
                SkVex = fminbnd(TanFun,app.PtsInd(1,1),app.PtsInd(2,1));
                %Finds the middle in terms of arc-length between critical
                %point and skull vertex
                MidPt = mean([app.PtsInd(1,1);SkVex]);
                
                %Finds forehead using a cost function considering the
                %curvature value at the peak and distance from the mid
                %point
                app.PeaksP = app.PeaksP(app.PeaksP(:,2)< SkVex & app.PeaksP(:,2)>app.PtsInd(1,1),:);
                Cost(:,1) = 1./abs(MidPt - app.PeaksP(:,2))./mean(1./abs(MidPt - app.PeaksP(:,2)));
                Cost(:,2) = app.PeaksP(:,3)./mean(app.PeaksP(:,3));
                Cost = 0.25.*Cost(:,1) + 0.75.*Cost(:,2);
                app.PeaksP = app.PeaksP(Cost == max(Cost),:);
                for i =1:size(app.PeaksP,1)
                    PeaksPCrds(i,1:2) = [Sum4Fourier(TotLen,CoefsX,app.PeaksP(i,2),Wn),Sum4Fourier(TotLen,CoefsY,app.PeaksP(i,2),Wn)];
                end
                %Calculate forehead difference from base line (Y value of
                %critical point and reflection) and the angle between the
                %baseline and the line connecting the forehead to the
                %critical point
                app.FHYDiff = PeaksPCrds(2) - mean([app.PtsInd(1,3);app.PtsInd(2,3)]);
                SkVYdiff = Sum4Fourier(TotLen,CoefsY,SkVex,Wn) - mean([app.PtsInd(1,3);app.PtsInd(2,3)]);
                app.FHYRt = app.FHYDiff./SkVYdiff;
                FhVec = (PeaksPCrds - app.PtsInd(1,2:3))./vecnorm((PeaksPCrds - app.PtsInd(1,2:3)),2,2);
                app.FHTrRt = tan(acos(dot(FhVec,[-1,0],2)));
                if size(PeaksPCrds,1) == 1
                   Frhd = scatter(app.UIAxes,PeaksPCrds(:,1),PeaksPCrds(:,2),120,'oc','filled');
                end
            end

            
            %             PeaksCrds1 = app.PeaksCrds;
%             PtsInd1 = app.PtsInd;
%             PeaksAll1 = app.PeaksAll;
%       Updates app structure values for figure and enables button
            if ~isempty(app.PtsInd)
                app.Bsel.XData = app.PtsInd(:,2);
                app.Bsel.YData = app.PtsInd(:,3);
%                 app.PPts.XData = PeaksPCrds(:,1);
%                 app.PPts.YData = PeaksPCrds(:,2);
                app.StLineGbP.XData = app.PtsInd(:,2);
                app.StLineGbP.YData = app.PtsInd(:,3);
%                 app.StLineGbO.XData = [app.PtsInd(1,2);app.OpiC(1)];
%                 app.StLineGbO.YData = [app.PtsInd(1,3);app.OpiC(2)];
                app.Button.Enable = "on";
            end
            if size(app.PtsInd,1) == 2
                app.Button.Enable = "on";
            end


        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            %Makes the calculations
            %Note that these are done using the original low smoothing
            %(Neff = 80%, w = 20%) of the number of coefficients
            Wn = app.Four.Wn;
            CuDist = app.Four.CuDist;
            CoefsX = app.Four.CoefsX;
            CoefsY = app.Four.CoefsY;
            TotLen = CuDist(end);
            
            %Arc length and coordinates of top of the cranium
            TpDist = (app.PtsInd(1,1):(app.PtsInd(2,1)-app.PtsInd(1,1))/99:app.PtsInd(2,1))';
            for i=1:size(TpDist,1)
                Xpred(i) = Sum4Fourier(TotLen,CoefsX,TpDist(i),Wn);
                Ypred(i) = Sum4Fourier(TotLen,CoefsY,TpDist(i),Wn);
            end
            %Centroid size is calculated based ONLY on the top of the carnium,
            %thus eliminating bias originating in differences the size of
            %the whole cranium as some are partly broken. 
            % Note that this assumes that the scales of the crania in the
            % images relative to their resolution are consistent!
            CntSz = sqrt(sum(vecnorm([Xpred,Ypred]-mean([Xpred,Ypred]),2,2)));
            for i=1:size(CuDist,1)
                Xpred(i) = Sum4Fourier(TotLen,CoefsX,CuDist(i),Wn);
                Ypred(i) = Sum4Fourier(TotLen,CoefsY,CuDist(i),Wn);
            end
            SklNPt = [Xpred'-mean(Xpred),Ypred'-mean(Ypred)]./CntSz;
            %Fourier coefficients, curve length, and all the other
            %variables returned to calling script are recalculated based on
            %the rescaled input
            [TotLen, CoefsX, CoefsY, ~, ~] = FourierCoefs(SklNPt, false);
            app.Cnt.TotLen = TotLen;
            app.Cnt.CoefsX = CoefsX;
            app.Cnt.CoefsY = CoefsY;
            app.Cnt.PtsInd = app.PtsInd(:,1)./(CuDist(end)./TotLen);
            app.Cnt.FhPt = app.PeaksP(:,2)./(CuDist(end)./TotLen);
            app.Cnt.OpiT = app.OpiT./(CuDist(end)./TotLen);
            app.Cnt.Size = CntSz;
            
            %Rescaling is also done based on the Glabella - Opistocranion
            %distance. This scalling is not used in the current study.
            GOLSz = vecnorm(app.OpiC-app.PtsInd(1,2:3),2,2);
            SklNPt = [Xpred',Ypred']./GOLSz;
            [TotLen, CoefsX, CoefsY, ~, ~] = FourierCoefs(SklNPt, false);
            app.GOL.TotLen = TotLen;
            app.GOL.CoefsX = CoefsX;
            app.GOL.CoefsY = CoefsY;
            app.GOL.PtsInd = app.PtsInd(:,1)./(CuDist(end)./TotLen);
            app.GOL.FhPt = app.PeaksP(:,2)./(CuDist(end)./TotLen);
            app.GOL.OpiT = app.OpiT./(CuDist(end)./TotLen);
            app.GOL.Size = GOLSz;
            
            %App closing signal
            app.Done = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowButtonMotionFcn = createCallbackFcn(app, @UIFigureWindowButtonMotion, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button_2 = uibutton(app.UIFigure, 'push');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PtSelector(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end